classdef ez_motion_correction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EZMotionCorrectionUIFigure  matlab.ui.Figure
        MotionCorrectionLabel       matlab.ui.control.Label
        FilestoProcessLabel         matlab.ui.control.Label
        ProcessedFilesLabel         matlab.ui.control.Label
        FilestoProcessListBox       matlab.ui.control.ListBox
        ProcessedFilesListBox       matlab.ui.control.ListBox
        AddFilesButton              matlab.ui.control.Button
        RemoveFileButton            matlab.ui.control.Button
        OpenFileButton              matlab.ui.control.Button
        ClearListButton             matlab.ui.control.Button
        SettingsPanel               matlab.ui.container.Panel
        NonrigidMotionCorrectionCheckBox  matlab.ui.control.CheckBox
        GridSizeLabel               matlab.ui.control.Label
        UpsamplingFactorLabel       matlab.ui.control.Label
        MaxShiftLabel               matlab.ui.control.Label
        InitialBatchSizeLabel       matlab.ui.control.Label
        BinWidthLabel               matlab.ui.control.Label
        SaveSettingsButton          matlab.ui.control.Button
        LoadSettingsButton          matlab.ui.control.Button
        GridSizeDropDown            matlab.ui.control.DropDown
        UpsamplingFactorEditField   matlab.ui.control.NumericEditField
        MaxShiftEditField           matlab.ui.control.NumericEditField
        InitialBatchSizeEditField   matlab.ui.control.NumericEditField
        BinWidthEditField           matlab.ui.control.NumericEditField
        NonrigidMotionCorrectionHelpButton  matlab.ui.control.Button
        GridSizeHelpButton          matlab.ui.control.Button
        UpsamplingFactorHelpButton  matlab.ui.control.Button
        MaxShiftHelpButton          matlab.ui.control.Button
        InitialBatchSizeHelpButton  matlab.ui.control.Button
        BinWidthHelpButton          matlab.ui.control.Button
        LoadSaveHelpButton          matlab.ui.control.Button
        SettingsLabel               matlab.ui.control.Label
        RunMotionCorrectionButton   matlab.ui.control.Button
        HelpButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        motcor % internal storage of GUI settings
    end
    
    methods (Access = private)
        
        % Reads GUI, stores data into motcor variable
        function parse_motcor(app)
            app.motcor.non_rigid = app.NonrigidMotionCorrectionCheckBox.Value;
            app.motcor.grid_size = app.GridSizeDropDown.Value;
            app.motcor.us_fac = app.UpsamplingFactorEditField.Value;
            app.motcor.max_shift = app.MaxShiftEditField.Value;
            app.motcor.initial_batch_size = app.InitialBatchSizeEditField.Value;
            app.motcor.bin_width = app.BinWidthEditField.Value;
        end
        
        % Writes motcor to GUI
        function write_motcor(app)
            app.NonrigidMotionCorrectionCheckBox.Value = app.motcor.non_rigid;
            app.GridSizeDropDown.Value = app.motcor.grid_size;
            app.UpsamplingFactorEditField.Value = app.motcor.us_fac;
            app.MaxShiftEditField.Value = app.motcor.max_shift;
            app.InitialBatchSizeEditField.Value = app.motcor.initial_batch_size;
            app.BinWidthEditField.Value = app.motcor.bin_width;
            app.FilestoProcessListBox.Items = app.motcor.to_process_list;    
            
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filepath = fileparts([mfilename('fullpath') '.m']);
            settings_file = fullfile(filepath, 'ez_settings.mat');
            if isfile(settings_file)
                if ismember('motcor_settings', who('-file', settings_file))
                    load(fullfile(filepath, 'ez_settings.mat'), 'motcor_settings');
                    app.motcor = motcor_settings;
                    write_motcor(app)
                end
            end
            
            if ~app.NonrigidMotionCorrectionCheckBox.Value
                app.GridSizeDropDown.Enable = 'off';
                app.GridSizeLabel.Enable = 'off'; drawnow
            end
            
            warning_text = '';
            if ~license('test', 'image_toolbox')
                warning_text = [warning_text newline '    Image Processing Toolbox'];
            end
            if ~license('test', 'distrib_computing_toolbox')
                warning_text = [warning_text newline '    Parallel Computing Toolbox'];
            end
            if ~license('test', 'statistics_toolbox')
                warning_text = [warning_text newline '    Statistics and Machine Learning Toolbox'];
            end
            if ~isempty(warning_text)
                warning_text = ['It seems like you do not have the following MATLAB toolboxes installed: ' newline warning_text newline newline 'Motion Correction will not work without these toolboxes.'];
                msgbox(warning_text, 'Warning');
            end
            
            if ~exist('NoRMCorreSetParms.m', 'file')
                warning_text = 'Cannot find NoRMCorre on MATLAB path. Please make sure you download NoRMCorre from https://github.com/porteralab/NoRMCorre and add it to MATLAB path. Motion Correction will not work without doing this.';
                msgbox(warning_text, 'Warning');
            end
        end

        % Button pushed function: AddFilesButton
        function AddFilesButtonPushed(app, event)
            supported_files = {'*.tif; *.tiff; *.avi; *.txt', ...
                'Supported Files (.tif, .tiff, .avi, .txt)';...
                '*.*', 'All Files'};
            
            [add_file, add_filepath] = uigetfile(supported_files, 'Choose file(s) to be processed.', 'MultiSelect', 'on');
            figure(app.EZMotionCorrectionUIFigure) % workaround to keep focus at UI window
            
            if ~iscell(add_file) && ~ischar(add_file)
                return
            end
            
            % Checks to see if only one item was added
            if ~iscell(add_file); add_file = cellstr(add_file); end
            
            for i = 1:length(add_file)
                full_add_files{i} = [add_filepath add_file{i}];
            end
            
            % Check for repeats, if not, add to list
            i = 0;
            while i < length(full_add_files)
                i = i + 1;
                cur_file = full_add_files{i};
                
                if sum(ismember(app.FilestoProcessListBox.Items, cur_file)) > 0 % If repeats, warning_text update
                    warning_text = ['File: ' cur_file ' is already on the list.'];
                    msgbox(warning_text, 'Warning');
                elseif strcmp(cur_file(end-3:end), '.txt')
                    fid = fopen(cur_file);
                    while 1
                        tline = fgetl(fid);
                        if ~ischar(tline), break, end
                        if isfile(tline)
                            [~, ~, file_ext] = fileparts(tline);
                            if strcmp(file_ext, '.tif') || strcmp(file_ext, '.tiff') || strcmp(file_ext, '.avi')
                                full_add_files = horzcat(full_add_files, cellstr(tline));
                            end
                        end
                    end
                    fclose(fid);
                else
                    app.FilestoProcessListBox.Items = horzcat(app.FilestoProcessListBox.Items, cellstr(cur_file)); drawnow
                end
            end
        end

        % Button pushed function: RemoveFileButton
        function RemoveFileButtonPushed(app, event)
            % Checks if anything is selected
            if isempty(app.FilestoProcessListBox.Value)
                return
            end
            
            [~, cur_pos] = ismember(app.FilestoProcessListBox.Value, app.FilestoProcessListBox.Items);
            
            % deal with selection
            if length(app.FilestoProcessListBox.Items) > 1
                if cur_pos == length(app.FilestoProcessListBox.Items)
                    app.FilestoProcessListBox.Value = app.FilestoProcessListBox.Items(cur_pos-1);
                else
                    app.FilestoProcessListBox.Value = app.FilestoProcessListBox.Items(cur_pos);
                end
            end
            
            app.FilestoProcessListBox.Items(cur_pos) = ''; drawnow % remove selection from list
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            % Checks if anything is selected
            if isempty(app.ProcessedFilesListBox.Value)
                return
            end
            
            file_string = app.ProcessedFilesListBox.Value;
            
            % Open file in the default program
            if ispc
                winopen(file_string);
            elseif ismac
                system(['open ' file_string ' &']);
            elseif isunix
                system(['xdg-open ' file_string ' &']);
            end
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.ProcessedFilesListBox.Items = {}; drawnow
        end

        % Button pushed function: RunMotionCorrectionButton
        function RunMotionCorrectionButtonPushed(app, event)
            if isempty(app.FilestoProcessListBox.Items)
                warning_text = 'No file has been added yet!';
                msgbox(warning_text, 'Warning');
                return
            end
            
            % disable run button during running
            app.RunMotionCorrectionButton.Enable = 'off'; drawnow
            
            parse_motcor(app)
            switch app.motcor.grid_size
                case '32 x 32'
                    grid_size = 32;
                case '48 x 48'
                    grid_size = 48;
                case '64 x 64'
                    grid_size = 64;
                case '96 x 96'
                    grid_size = 96;
                case '128 x 128'
                    grid_size = 128;
            end
            
            % Move files to process highlight to first position
            app.FilestoProcessListBox.Value = app.FilestoProcessListBox.Items(1);
            
            % Find number of files to be run
            file_num = length(app.FilestoProcessListBox.Items);
            
            for i = 1:file_num
                filename = app.FilestoProcessListBox.Value;
                disp(['Starting motion correction for ' filename]);
                FOV = size(read_file(filename, 1, 1));
                
                [fpath, fname] = fileparts(filename);
                filename_mcor = fullfile(fpath, [fname '_mcor.tif']);
                
                if isfile(filename_mcor)
                    msgbox(['File ' filename_mcor ' already exist. Will improvise a different file name...'], 'Warning')
                    filename_mcor = fullfile(fpath, [fname '_mcor_' datestr(now, 30) '.tif']);
                end
                
                % Set options
                if ~app.motcor.non_rigid
                    options = NoRMCorreSetParms(...
                        'd1', FOV(1), ...
                        'd2', FOV(2), ...
                        'us_fac', app.motcor.us_fac, ...
                        'max_shift', app.motcor.max_shift, ...
                        'init_batch', app.motcor.initial_batch_size, ...
                        'bin_width', app.motcor.bin_width, ...
                        'output_type', 'tiff', ... % currently hard-coded to save tiff
                        'tiff_filename', filename_mcor);
                else
                    options = NoRMCorreSetParms(...
                        'd1', FOV(1), ...
                        'd2', FOV(2), ...
                        'grid_size', [grid_size, grid_size], ...
                        'us_fac', app.motcor.us_fac, ...
                        'max_shift', app.motcor.max_shift, ...
                        'init_batch', app.motcor.initial_batch_size, ...
                        'bin_width', app.motcor.bin_width, ...
                        'output_type', 'tiff', ...
                        'tiff_filename', filename_mcor);
                end
                
                % Run motion correction
                [~, shiftg_1, template, options_mc] = normcorre_batch_even(filename, options);               
                
                % Save settings in MAT file
                filename_mat = [filename_mcor(1:end-4) '.mat'];
                motcor_settings = app.motcor;
                save(filename_mat, 'motcor_settings', 'template', 'options_mc')
                
                % rebuild 尝试移动另一个通道
                if(app.motcor.has_channel2)
                    filename_c2 = [filename(1:end-4) '-follow.tif'];
                    filename_mcor_c2 = [filename_c2(1:end-4) '_mcor.tif'];
                    options = NoRMCorreSetParms(...
                            'd1', FOV(1), ...
                            'd2', FOV(2), ...
                            'us_fac', app.motcor.us_fac, ...
                            'max_shift', app.motcor.max_shift, ...
                            'init_batch', app.motcor.initial_batch_size, ...
                            'bin_width', app.motcor.bin_width, ...
                            'output_type', 'tiff', ... % currently hard-coded to save tiff
                            'tiff_filename', filename_mcor_c2, ...
                            'shift_g_in', shiftg_1);
                     
                    normcorre_batch_even(filename_c2, options);
                end 
                % end rebuild
                
                % Update file lists
                app.FilestoProcessListBox.Items(1) = '';
                app.ProcessedFilesListBox.Items = horzcat(app.ProcessedFilesListBox.Items, cellstr(filename_mcor)); drawnow
            end
            
            app.RunMotionCorrectionButton.Enable = 'on'; drawnow
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/porteralab/EZcalcium/wiki/Motion-Correction') %Load documentation
        end

        % Value changed function: NonrigidMotionCorrectionCheckBox
        function NonrigidMotionCorrectionCheckBoxValueChanged(app, event)
            if app.NonrigidMotionCorrectionCheckBox.Value
                app.GridSizeDropDown.Enable = 'on';
                app.GridSizeLabel.Enable = 'on'; drawnow
            else
                app.GridSizeDropDown.Enable = 'off';
                app.GridSizeLabel.Enable = 'off'; drawnow
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.mat');
            figure(app.EZMotionCorrectionUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            parse_motcor(app)
            motcor_settings = app.motcor;
            
            full_filename = [filepath filename];
            save(full_filename, 'motcor_settings');
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat');
            figure(app.EZMotionCorrectionUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            full_filename = [filepath filename];
            
            % Check if file is valid
            if ~ismember('motcor_settings', who('-file', full_filename))
                warning_text = 'The selected file is not a valid settings file.';
                msgbox(warning_text, 'Warning')
                return
            end
            
            load(full_filename, 'motcor_settings');
            app.motcor = motcor_settings;
            write_motcor(app)
        end

        % Button pushed function: NonrigidMotionCorrectionHelpButton
        function NonrigidMotionCorrectionHelpButtonPushed(app, event)
            msgbox("Choose whether to use non-rigid motion correction or not. Non-rigid Motion Correction splits the field of view into a number of overlapping patches to correct for within-frame motion artifacts. While this can give more accurate results, it is considerably slower than rigid motion correction.", "Help", 'replace')
        end

        % Button pushed function: GridSizeHelpButton
        function GridSizeHelpButtonPushed(app, event)
            msgbox("Grid Size defines the size of each patch in pixels when using non-rigid motion correction. This option is not available if Non-rigid Motion Correction is not selected.", "Help", 'replace')
        end

        % Button pushed function: UpsamplingFactorHelpButton
        function UpsamplingFactorHelpButtonPushed(app, event)
            msgbox("Upsampling Factor defines the upsampling factor for subpixel registration. Higher values helps reducing the smearing effect while being slower. Usually no need to change.", "Help", 'replace')
        end

        % Button pushed function: MaxShiftHelpButton
        function MaxShiftHelpButtonPushed(app, event)
            msgbox("Max Shift defines the maximum rigid shift in pixels allowed in each direction. Consider increase this value when there is large amplitude of motion.", "Help", 'replace')
        end

        % Button pushed function: InitialBatchSizeHelpButton
        function InitialBatchSizeHelpButtonPushed(app, event)
            msgbox("Initial Batch Size defines the number of frames from the beginning used for calculating the initial template. Change this to a lower value if you run into memory issues.", "Help", 'replace')
        end

        % Button pushed function: BinWidthHelpButton
        function BinWidthHelpButtonPushed(app, event)
            msgbox("Bin Width defines the number of frames of each bin, over which the registered frames are averaged to update the template. Change this to a lower value if you run into memory issues.", "Help", 'replace')
        end

        % Button pushed function: LoadSaveHelpButton
        function LoadSaveHelpButtonPushed(app, event)
            msgbox("The Save Settings button allows the user to manually save all settings under a specific file name of one’s choosing. Settings are saved as .mat files. These include all settings in the Settings section. Note that EZcalcium will automatically save the settings into a .mat file (see below), so manually saving the settings is usually not necessary. The Load Settings button allows one to load all saved settings in future sessions. You can load the automatically generated .mat file (which is the same file that saves ROI Detection and ROI Refinement results) to check the settings used to generate the results.", "Help", 'replace')
        end

        % Close request function: EZMotionCorrectionUIFigure
        function EZMotionCorrectionUIFigureCloseRequest(app, event)
            parse_motcor(app)
            motcor_settings = app.motcor;
            filepath = fileparts([mfilename('fullpath') '.m']);
            if isfile(fullfile(filepath, 'ez_settings.mat'))
                save(fullfile(filepath, 'ez_settings.mat'), 'motcor_settings', '-append')
            else
                save(fullfile(filepath, 'ez_settings.mat'), 'motcor_settings')
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EZMotionCorrectionUIFigure = uifigure('Visible', 'off');
            app.EZMotionCorrectionUIFigure.Name = 'EZ Motion Correction';
            app.EZMotionCorrectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @EZMotionCorrectionUIFigureCloseRequest, true);
            app.MotionCorrectionLabel = uilabel(app.EZMotionCorrectionUIFigure);
            app.MotionCorrectionLabel.Text = 'Motion Correction';
            app.FilestoProcessLabel = uilabel(app.EZMotionCorrectionUIFigure);
            app.FilestoProcessLabel.Text = 'Files to Process';
            app.ProcessedFilesLabel = uilabel(app.EZMotionCorrectionUIFigure);
            app.ProcessedFilesLabel.Text = 'Processed Files';
            app.FilestoProcessListBox = uilistbox(app.EZMotionCorrectionUIFigure);
            app.FilestoProcessListBox.Items = {};
            app.FilestoProcessListBox.Value = {};
            app.ProcessedFilesListBox = uilistbox(app.EZMotionCorrectionUIFigure);
            app.ProcessedFilesListBox.Items = {};
            app.ProcessedFilesListBox.Value = {};
            app.AddFilesButton = uibutton(app.EZMotionCorrectionUIFigure, 'push');
            app.AddFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilesButtonPushed, true);
            app.AddFilesButton.Text = {'Add Files...'; ''};
            app.RemoveFileButton = uibutton(app.EZMotionCorrectionUIFigure, 'push');
            app.RemoveFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFileButtonPushed, true);
            app.RemoveFileButton.Text = 'Remove File';
            app.OpenFileButton = uibutton(app.EZMotionCorrectionUIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.ClearListButton = uibutton(app.EZMotionCorrectionUIFigure, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.SettingsPanel = uipanel(app.EZMotionCorrectionUIFigure);
            app.NonrigidMotionCorrectionCheckBox = uicheckbox(app.SettingsPanel);
            app.NonrigidMotionCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @NonrigidMotionCorrectionCheckBoxValueChanged, true);
            app.NonrigidMotionCorrectionCheckBox.Text = 'Non-rigid Motion Correction';
            app.GridSizeLabel = uilabel(app.SettingsPanel);
            app.GridSizeLabel.Text = 'Grid Size';
            app.UpsamplingFactorLabel = uilabel(app.SettingsPanel);
            app.UpsamplingFactorLabel.Text = 'Upsampling Factor';
            app.MaxShiftLabel = uilabel(app.SettingsPanel);
            app.MaxShiftLabel.Text = 'Max Shift';
            app.InitialBatchSizeLabel = uilabel(app.SettingsPanel);
            app.InitialBatchSizeLabel.Text = 'Initial Batch Size';
            app.BinWidthLabel = uilabel(app.SettingsPanel);
            app.BinWidthLabel.Text = 'Bin Width';
            app.SaveSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.GridSizeDropDown = uidropdown(app.SettingsPanel);
            app.GridSizeDropDown.Items = {'32 x 32', '48 x 48', '64 x 64', '96 x 96', '128 x 128'};
            app.GridSizeDropDown.Value = '64 x 64';
            app.UpsamplingFactorEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.UpsamplingFactorEditField.Value = 50;
            app.MaxShiftEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MaxShiftEditField.Value = 15;
            app.InitialBatchSizeEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.InitialBatchSizeEditField.Value = 20;
            app.BinWidthEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BinWidthEditField.Value = 200;
            app.NonrigidMotionCorrectionHelpButton = uibutton(app.SettingsPanel, 'push');
            app.NonrigidMotionCorrectionHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NonrigidMotionCorrectionHelpButtonPushed, true);
            app.NonrigidMotionCorrectionHelpButton.Text = '?';
            app.GridSizeHelpButton = uibutton(app.SettingsPanel, 'push');
            app.GridSizeHelpButton.ButtonPushedFcn = createCallbackFcn(app, @GridSizeHelpButtonPushed, true);
            app.GridSizeHelpButton.Text = '?';
            app.UpsamplingFactorHelpButton = uibutton(app.SettingsPanel, 'push');
            app.UpsamplingFactorHelpButton.ButtonPushedFcn = createCallbackFcn(app, @UpsamplingFactorHelpButtonPushed, true);
            app.UpsamplingFactorHelpButton.Text = '?';
            app.MaxShiftHelpButton = uibutton(app.SettingsPanel, 'push');
            app.MaxShiftHelpButton.ButtonPushedFcn = createCallbackFcn(app, @MaxShiftHelpButtonPushed, true);
            app.MaxShiftHelpButton.Text = '?';
            app.InitialBatchSizeHelpButton = uibutton(app.SettingsPanel, 'push');
            app.InitialBatchSizeHelpButton.ButtonPushedFcn = createCallbackFcn(app, @InitialBatchSizeHelpButtonPushed, true);
            app.InitialBatchSizeHelpButton.Text = '?';
            app.BinWidthHelpButton = uibutton(app.SettingsPanel, 'push');
            app.BinWidthHelpButton.ButtonPushedFcn = createCallbackFcn(app, @BinWidthHelpButtonPushed, true);
            app.BinWidthHelpButton.Text = '?';
            app.LoadSaveHelpButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSaveHelpButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSaveHelpButtonPushed, true);
            app.LoadSaveHelpButton.Text = '?';
            app.SettingsLabel = uilabel(app.EZMotionCorrectionUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.RunMotionCorrectionButton = uibutton(app.EZMotionCorrectionUIFigure, 'push');
            app.RunMotionCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunMotionCorrectionButtonPushed, true);
            app.RunMotionCorrectionButton.Text = 'Run Motion Correction';
            app.HelpButton = uibutton(app.EZMotionCorrectionUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.EZMotionCorrectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ez_motion_correction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EZMotionCorrectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EZMotionCorrectionUIFigure)
        end
    end
end